classdef Vehicle_Lat_Error < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        VehicleLateralControllerPanelLabel  matlab.ui.control.Label
        VehicleSpecPanel             matlab.ui.container.Panel
        CalculateButton              matlab.ui.control.Button
        MassEditFieldLabel           matlab.ui.control.Label
        MassEditField                matlab.ui.control.NumericEditField
        IzEditFieldLabel             matlab.ui.control.Label
        IzEditField                  matlab.ui.control.NumericEditField
        LatVeloEditFieldLabel        matlab.ui.control.Label
        LatVeloEditField             matlab.ui.control.NumericEditField
        frontlengthEditFieldLabel    matlab.ui.control.Label
        frontlengthEditField         matlab.ui.control.NumericEditField
        fronttirestiffnessEditFieldLabel  matlab.ui.control.Label
        fronttirestiffnessEditField  matlab.ui.control.NumericEditField
        rearlengthEditFieldLabel     matlab.ui.control.Label
        rearlengthEditField          matlab.ui.control.NumericEditField
        reartirestiffnessEditFieldLabel  matlab.ui.control.Label
        reartirestiffnessEditField   matlab.ui.control.NumericEditField
        RadiusofCruveEditFieldLabel  matlab.ui.control.Label
        RadiusofCruveEditField       matlab.ui.control.NumericEditField
        ControllerDesignPanel        matlab.ui.container.Panel
        OSEditFieldLabel             matlab.ui.control.Label
        OSEditField                  matlab.ui.control.NumericEditField
        TsEditFieldLabel             matlab.ui.control.Label
        TsEditField                  matlab.ui.control.NumericEditField
        PoleTable                    matlab.ui.control.Table
        OutputTextAreaLabel          matlab.ui.control.Label
        OutputTextArea               matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %obtain value from user input
            m = app.MassEditField.Value;
            Iz = app.IzEditField.Value;
            Caf = app.fronttirestiffnessEditField.Value;
            Car = app.reartirestiffnessEditField.Value;
            Vx = app.LatVeloEditField.Value;
            lf = app.frontlengthEditField.Value;
            lr = app.rearlengthEditField.Value;
            R = app.RadiusofCruveEditField.Value;
            Mp = app.OSEditField.Value;
            Ts = app.TsEditField.Value;
            
            % error state calculation
            r22 = (-2*Caf*lf-2*Car*lr)/(m*Vx);
            r23 = (2*Caf+2*Car)/m;
            r24 = (-2*Caf*lf+2*Car*lr)/(m*Vx);
            r42 = (2*Caf*lf-2*Car*lr)/(Iz*Vx);
            r43 = (2*Caf*lf-2*Car*lr)/Iz;
            r44 = (2*Caf*lf^2+2*Car*lr^2)/(Iz*Vx);
            A = [0 1 0 0;0 r22 r23 r24; 0 0 0 1; 0 r42 r43 r44];
            B1 = [0 2*Caf/m 0 2*Caf*lf/Iz]';
            B2 = [0 r24-Vx 0 r44]';
            B = [B1 B2];
            C  = [1 0 0 0;0 0 1 0];
            sys_e = ss(A,B,C,[]);
        
            %R = 1000;
            phid_des = Vx/R;
            opt = stepDataOptions('StepAmplitude',phid_des);
            t1 = (0:0.1:10)';
            %Ts = 1; Mp = 10;
            zeta = -log(Mp/100)/(sqrt(pi^2+(log(Mp/100)^2)));
            wn = 4/(zeta*Ts);
            p1 = -zeta*wn+wn*sqrt(zeta^2-1); %(-b+sqrt(b^2-4*a*c))/2a
            p2 = -zeta*wn-wn*sqrt(zeta^2-1);
            p3 = 10*(-zeta*wn);
            p4 = p3-1;
            sp_des = [p1 p2 p3 p4]; %desired poles derived from settling time and overshoot
            
            Ks = place(A,B(:,1),sp_des);% using Vx=30 to design Ks
            Acls = A-B(:,1)*Ks;
            sys_cls = ss(Acls,B(:,2),C,0);
            
            app.PoleTable.Data = sp_des;
            zetas = num2str(zeta);
            zt = strcat('zeta: ',zetas);
            app.OutputTextArea.Value = zt;
            
            %plotting poles and zeros
            p1i = imag(p1);
            p1r = real(p1);
            p2i = imag(p2);
            p2r = real(p2);
            p3i = imag(p3);
            p3r = real(p3);
            p4i = imag(p4);
            p4r = real(p4);
            plot(app.UIAxes,p1r,p1i,"b*",p2r,p2i,'g*',p3r,p3i,'r*',p4r,p4i,'*');
            title(app.UIAxes,'Desired Pole Graph')
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Desired Pole Graph')
            xlabel(app.UIAxes, 'Re')
            ylabel(app.UIAxes, 'Im')
            app.VehicleLateralControllerPanelLabel = uilabel(app.UIFigure);
            app.VehicleLateralControllerPanelLabel.Text = 'Vehicle Lateral Controller Panel';
            app.VehicleSpecPanel = uipanel(app.UIFigure);
            app.VehicleSpecPanel.Title = 'Vehicle Spec';
            app.CalculateButton = uibutton(app.VehicleSpecPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MassEditFieldLabel = uilabel(app.VehicleSpecPanel);
            app.MassEditFieldLabel.Text = 'Mass';
            app.MassEditField = uieditfield(app.VehicleSpecPanel, 'numeric');
            app.MassEditField.Value = 1573;
            app.IzEditFieldLabel = uilabel(app.VehicleSpecPanel);
            app.IzEditFieldLabel.Text = 'Iz';
            app.IzEditField = uieditfield(app.VehicleSpecPanel, 'numeric');
            app.IzEditField.Value = 2873;
            app.LatVeloEditFieldLabel = uilabel(app.VehicleSpecPanel);
            app.LatVeloEditFieldLabel.Text = 'Lat Velo';
            app.LatVeloEditField = uieditfield(app.VehicleSpecPanel, 'numeric');
            app.LatVeloEditField.Value = 30;
            app.frontlengthEditFieldLabel = uilabel(app.VehicleSpecPanel);
            app.frontlengthEditFieldLabel.Text = 'front length';
            app.frontlengthEditField = uieditfield(app.VehicleSpecPanel, 'numeric');
            app.frontlengthEditField.Value = 1.1;
            app.fronttirestiffnessEditFieldLabel = uilabel(app.VehicleSpecPanel);
            app.fronttirestiffnessEditFieldLabel.Text = 'front tire stiffness';
            app.fronttirestiffnessEditField = uieditfield(app.VehicleSpecPanel, 'numeric');
            app.fronttirestiffnessEditField.Value = 80000;
            app.rearlengthEditFieldLabel = uilabel(app.VehicleSpecPanel);
            app.rearlengthEditFieldLabel.Text = 'rear length';
            app.rearlengthEditField = uieditfield(app.VehicleSpecPanel, 'numeric');
            app.rearlengthEditField.Value = 1.58;
            app.reartirestiffnessEditFieldLabel = uilabel(app.VehicleSpecPanel);
            app.reartirestiffnessEditFieldLabel.Text = 'rear tire stiffness';
            app.reartirestiffnessEditField = uieditfield(app.VehicleSpecPanel, 'numeric');
            app.reartirestiffnessEditField.Value = 80000;
            app.RadiusofCruveEditFieldLabel = uilabel(app.VehicleSpecPanel);
            app.RadiusofCruveEditFieldLabel.Text = 'Radius of Cruve';
            app.RadiusofCruveEditField = uieditfield(app.VehicleSpecPanel, 'numeric');
            app.RadiusofCruveEditField.Value = 1000;
            app.ControllerDesignPanel = uipanel(app.UIFigure);
            app.ControllerDesignPanel.Title = 'Controller Design';
            app.OSEditFieldLabel = uilabel(app.ControllerDesignPanel);
            app.OSEditFieldLabel.Text = '% OS';
            app.OSEditField = uieditfield(app.ControllerDesignPanel, 'numeric');
            app.OSEditField.Value = 10;
            app.TsEditFieldLabel = uilabel(app.ControllerDesignPanel);
            app.TsEditFieldLabel.Text = 'Ts';
            app.TsEditField = uieditfield(app.ControllerDesignPanel, 'numeric');
            app.TsEditField.Value = 1;
            app.PoleTable = uitable(app.UIFigure);
            app.PoleTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.PoleTable.RowName = {};
            app.OutputTextAreaLabel = uilabel(app.UIFigure);
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Vehicle_Lat_Error

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end